classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AlgoritmoGenticoLabel     matlab.ui.control.Label
        FuncinaoptimizarLabel     matlab.ui.control.Label
        NmerodeelementosdelageneracinLabel  matlab.ui.control.Label
        NmerodeelementosparaelitismoLabel  matlab.ui.control.Label
        NmerodegeneracionesLabel  matlab.ui.control.Label
        Umbral1Label              matlab.ui.control.Label
        UmbraldecruzamientoLabel  matlab.ui.control.Label
        UmbraldemutacinLabel      matlab.ui.control.Label
        ResultadosLabel           matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        YLabel                    matlab.ui.control.Label
        FuncinxyLabel             matlab.ui.control.Label
        CalcularButton            matlab.ui.control.Button
        GraficarButton            matlab.ui.control.Button
        functionLabel             matlab.ui.control.Label
        elementosGeneracion       matlab.ui.control.NumericEditField
        elementosElitismo         matlab.ui.control.NumericEditField
        generaciones              matlab.ui.control.NumericEditField
        umbral1                   matlab.ui.control.NumericEditField
        umbralCruzamiento         matlab.ui.control.NumericEditField
        umbralMutacion            matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            
            numElem = app.elementosGeneracion.Value;
            umbral1s = app.umbral1.Value;
            umbralCruz = app.umbralCruzamiento.Value;
            umbralMut = app.umbralMutacion.Value;
            elemElitismo = app.elementosElitismo.Value;
            numGeneraciones = app.generaciones.Value;
            
            elemG = app.elementosGeneracion.Value;
            elemE = app.elementosElitismo.Value;
            gens = app.generaciones.Value;
            
            %             umb = app.umbral1.Value;
            %             umbC = app.umbralCruzamiento.Value;
            %             umbM = app.umbralMutacion.Value;
            %             
             app.Label.Text = 'Calculando...';
             app.Label_2.Text = 'Calculando...';
             app.Label_3.Text = 'Calculando...';
            
            %% Variables
            patronX = codifPseudoBinaria( -360, 360 );
            patronY = codifPseudoBinaria( -360, 360 );
            
            %             printCodPseudBin(patronX);
            %             printCodPseudBin(patronY);
            
            %% Población inicial
            [ pobX, valX ] = generarPob( numElem, umbral1s, patronX );
            [ pobY, valY ] = generarPob( numElem, umbral1s, patronY );
            
            valOrdX = sort( valX );
            valOrdY = sort( valY );
            
            %             figure; plot( valOrdX, 'o' );
            %             figure; plot( valOrdY, 'o' );
            %             
            %% Población actual y sus patrones
            pobActual(1, :, 1:size(pobX,2)) = pobX;
            pobActual(2, :, 1:size(pobY,2)) = pobY;
            
            patrones(1) = patronX;
            patrones(2) = patronY;
            
            %% Generaciones
            
            [ fxy ] = funcionAdaptProy( valX, valY);
            fxyHist = fxy;
            for i = 1 : numGeneraciones
                [ pobNueva, valNuevos ] = generarSigPob( numElem, pobActual, fxy, patrones, elemElitismo, umbralCruz, umbralMut );
                [ fxy ] = funcionAdaptProy( valNuevos(1, :)', valNuevos(2, :)');
                fxyHist = horzcat( fxyHist , fxy );
            end
            
            [ vx, vy, vf, winnerPos ] = elemGanador(fxy, valNuevos);
            
            setappdata(app.UIFigure,'HistData',fxyHist);
            setappdata(app.UIFigure,'numElem',numElem);
            setappdata(app.UIFigure,'numGen',numGeneraciones);
            setappdata(app.UIFigure,'winPos',winnerPos);
            setappdata(app.UIFigure,'winVal',vf);
            
            app.Label.Text = num2str(vx);
            app.Label_2.Text = num2str(vy);
            app.Label_3.Text = num2str(vf);
            
            set(app.GraficarButton,'Enable','on')
            
            %             %% Visualización
            %             colorCode = ['b'; 'k'; 'r'; 'g'; 'y'; 'c'; 'm'];
            %             ejeX = 1 : numElem;
            %             figure;
            %             plot(ejeX, fxyHist(:,1), 'b', 'Linewidth', 1); hold on
            %             for i = 1 : numGeneraciones
            %                 plot(ejeX, fxyHist(:,((i * 3) + 1)), colorCode(mod(i, 7) + 1), 'Linewidth', 1);
            %             end
            %             title('Comparación');
            %             grid on;
            %             hold off;
            %             
            %             figure;
            %             plot(ejeX, fxyHist(:,1), '*', 'Linewidth', 1); hold on
            %             title('Población 0');
            %             grid on;
            %             hold off;
            %             for i = 1 : numGeneraciones
            %                 figure;
            %                 plot(ejeX, fxyHist(:,((i * 3) + 1)), '*', 'Linewidth', 1); hold on
            %                 title(['Población ' num2str(i)]);
            %                 grid on;
            %                 hold off;
            %             end
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            fxyHist = getappdata(app.UIFigure,'HistData');
            numElem = getappdata(app.UIFigure,'numElem');
            numGeneraciones = getappdata(app.UIFigure,'numGen');
            winPos = getappdata(app.UIFigure,'winPos');
            winVal = getappdata(app.UIFigure,'winVal');
            
            %%%% Visualización
            %% Gráfico General
            colorCode = ['b'; 'k'; 'r'; 'g'; 'y'; 'c'; 'm'];
            ejeX = 1 : numElem;
            figure;
            plot(ejeX, fxyHist(:,1), 'b', 'Linewidth', 1); hold on
            for i = 1 : numGeneraciones
                plot(ejeX, fxyHist(:,((i * 3) + 1)), colorCode(mod(i, 7) + 1), 'Linewidth', 1);
            end
            title('Comparación General');
            grid on;
            hold off;
            
            % Gráfico Primera y Última
            ejeX = 1 : numElem;
            figure;
            plot(ejeX, fxyHist(:,1), 'b', 'Linewidth', 1); hold on
            plot(ejeX, fxyHist(:,((numGeneraciones * 3) + 1)), colorCode(mod(numGeneraciones, 7) + 1), 'Linewidth', 1);
            plot(winPos, winVal, '*r', 'Linewidth', 4);
            title('Comparación Primera y Últimá Generación');
            legend('Primer Gen.', 'Última Gen.', 'Elem. Ganador','Location', 'northeastoutside');
            grid on;
            hold off;            
            
%             % Gráficos Independientes
%             figure;
%             plot(ejeX, fxyHist(:,1), '*', 'Linewidth', 1); hold on
%             title('Población 0');
%             grid on;
%             hold off;
%             for i = 1 : numGeneraciones
%                 figure;
%                 plot(ejeX, fxyHist(:,((i * 3) + 1)), '*', 'Linewidth', 1); hold on
%                 title(['Población ' num2str(i)]);
%                 grid on;
%                 hold off;
%             end
        end

        % Value changed function: elementosElitismo
        function elementosElitismoValueChanged(app, event)
            elit = app.elementosElitismo.Value;
            gen = app.elementosGeneracion.Value;
            if(rem(gen, 2) == 0)
                if(rem(elit, 2) ~= 0)
                    uialert(app.UIFigure,'Los elementos por elitismo deben ser pares si el número de elementos totales es par','Error');                    
                end
            else
                if(rem(elit, 2) == 0)
                    uialert(app.UIFigure,'Los elementos por elitismo deben ser impares si el número de elementos totales es impar','Error'); 
                end
            end
            if(elit > gen)
                uialert(app.UIFigure,'Los elementos por elitismo deben ser menores al número de elementos totales','Error');                    
            end
        end

        % Value changed function: elementosGeneracion
        function elementosGeneracionValueChanged(app, event)
            elit = app.elementosElitismo.Value;
            gen = app.elementosGeneracion.Value;
            if(rem(gen, 2) == 0)
                if(rem(elit, 2) ~= 0)
                    uialert(app.UIFigure,'Los elementos por elitismo deben ser pares si el número de elementos totales es par','Error');                    
                end
            else
                if(rem(elit, 2) == 0)
                    uialert(app.UIFigure,'Los elementos por elitismo deben ser impares si el número de elementos totales es impar','Error'); 
                end
            end
            if(elit > gen)
                uialert(app.UIFigure,'Los elementos por elitismo deben ser menores al número de elementos totales','Error');                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AlgoritmoGenticoLabel = uilabel(app.UIFigure);
            app.AlgoritmoGenticoLabel.Text = 'Algoritmo Genético';
            app.FuncinaoptimizarLabel = uilabel(app.UIFigure);
            app.FuncinaoptimizarLabel.Text = 'Función a optimizar:';
            app.NmerodeelementosdelageneracinLabel = uilabel(app.UIFigure);
            app.NmerodeelementosdelageneracinLabel.Text = 'Número de elementos de la generación';
            app.NmerodeelementosparaelitismoLabel = uilabel(app.UIFigure);
            app.NmerodeelementosparaelitismoLabel.Text = 'Número de elementos para elitismo';
            app.NmerodegeneracionesLabel = uilabel(app.UIFigure);
            app.NmerodegeneracionesLabel.Text = 'Número de generaciones';
            app.Umbral1Label = uilabel(app.UIFigure);
            app.Umbral1Label.Text = 'Umbral 1';
            app.UmbraldecruzamientoLabel = uilabel(app.UIFigure);
            app.UmbraldecruzamientoLabel.Text = 'Umbral de cruzamiento';
            app.UmbraldemutacinLabel = uilabel(app.UIFigure);
            app.UmbraldemutacinLabel.Text = 'Umbral de mutación';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.FuncinxyLabel = uilabel(app.UIFigure);
            app.FuncinxyLabel.Text = 'Función(x, y)';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Enable = 'off';
            app.GraficarButton.Text = 'Graficar';
            app.functionLabel = uilabel(app.UIFigure);
            app.functionLabel.Text = '𝑓(𝑥, 𝑦) = |𝑠𝑖𝑛(𝑥) + 𝑐𝑜𝑠(𝑦) + √|𝑥 − 𝑦||';
            app.elementosGeneracion = uieditfield(app.UIFigure, 'numeric');
            app.elementosGeneracion.ValueChangedFcn = createCallbackFcn(app, @elementosGeneracionValueChanged, true);
            app.elementosGeneracion.Limits = [1 Inf];
            app.elementosGeneracion.Value = 100;
            app.elementosElitismo = uieditfield(app.UIFigure, 'numeric');
            app.elementosElitismo.ValueChangedFcn = createCallbackFcn(app, @elementosElitismoValueChanged, true);
            app.elementosElitismo.Limits = [1 Inf];
            app.elementosElitismo.Value = 4;
            app.generaciones = uieditfield(app.UIFigure, 'numeric');
            app.generaciones.Limits = [1 Inf];
            app.generaciones.Value = 5;
            app.umbral1 = uieditfield(app.UIFigure, 'numeric');
            app.umbral1.Limits = [0 1];
            app.umbral1.ValueDisplayFormat = '%11.10g';
            app.umbral1.Value = 0.5;
            app.umbralCruzamiento = uieditfield(app.UIFigure, 'numeric');
            app.umbralCruzamiento.Limits = [0 1];
            app.umbralCruzamiento.ValueDisplayFormat = '%11.10g';
            app.umbralCruzamiento.Value = 0.5;
            app.umbralMutacion = uieditfield(app.UIFigure, 'numeric');
            app.umbralMutacion.Limits = [0 1];
            app.umbralMutacion.ValueDisplayFormat = '%11.10g';
            app.umbralMutacion.Value = 0.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end